classdef SmartImageToolbox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TabGroup              matlab.ui.container.TabGroup
        EnhancementTab        matlab.ui.container.Tab
        UploadRefernceButton  matlab.ui.control.Button
        ParameterEditField    matlab.ui.control.NumericEditField
        ParameterLabel        matlab.ui.control.Label
        OperationLabel        matlab.ui.control.Label
        DropDown              matlab.ui.control.DropDown
        ApplyButton           matlab.ui.control.Button
        UploadImageButton     matlab.ui.control.Button
        ProcessedAxes         matlab.ui.control.UIAxes
        OriginalAxes          matlab.ui.control.UIAxes
        SegmentationTab       matlab.ui.container.Tab
        TransformationTab     matlab.ui.container.Tab
        DigitrecognitionTab   matlab.ui.container.Tab
    end

    
    properties (Access = private)
        OriginalImage % Description
        ReferenceImage
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            appFolder = fileparts(which(class(app)));
            projectRoot = fileparts(appFolder);

            addpath(genpath(fullfile(projectRoot, 'modules')));
            addpath(genpath(fullfile(projectRoot, 'recognition')));

            disp('Smart Image Processing Toolbox initialized.');
        end

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
            [file, path] = uigetfile( ...
                {'*.jpg;*.jpeg;*.png;*.bmp', 'Image Files'}, ...
                'Select an Image');

            if isequal(file, 0)
                return;
            end

            img = imread(fullfile(path, file));

            app.OriginalImage = img;

            imshow(img, 'Parent', app.OriginalAxes);
            imshow(img, 'Parent', app.ProcessedAxes);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)

            % Check whether an image has been uploaded
    if isempty(app.OriginalImage)
        uialert(app.UIFigure, ...
            'Please upload an image first.', ...
            'No Image');
        return;
    end

    % Get selected operation and parameter
    operation = app.DropDown.Value;
    parameter = app.ParameterEditField.Value;

    try
        switch operation

            case 'Histogram Equalization'
                result = applyHistogramEqualization( ...
                    app.OriginalImage);

            case 'Histogram Matching'
                if isempty(app.ReferenceImage)
                    uialert(app.UIFigure, ...
                        'Please upload a reference image.', ...
                        'Reference Required');
                    return;
                end

                result = applyHistogramMatching( ...
                    app.OriginalImage, app.ReferenceImage);

            case 'Brightness Adjustment'
                result = adjustBrightness( ...
                    app.OriginalImage, parameter);

            case 'Contrast Adjustment'
                result = adjustContrast( ...
                    app.OriginalImage, parameter);

            case 'Mean Filter'
                kernelSize = round(parameter);
                if kernelSize < 1
                    error('Kernel size must be positive.');
                end

                result = applyMeanFilter( ...
                    app.OriginalImage, kernelSize);

            case 'Median Filter'
                kernelSize = round(parameter);
                if kernelSize < 1 || mod(kernelSize, 2) == 0
                    error('Median kernel size must be a positive odd integer.');
                end

                result = applyMedianFilter( ...
                    app.OriginalImage, kernelSize);

            case 'Laplacian Sharpening'
                result = applyLaplacianSharpening( ...
                    app.OriginalImage, parameter);

            case 'High-Pass Sharpening'
                result = applyHighPassSharpening( ...
                    app.OriginalImage, parameter);

            case 'Fourier Low-Pass'
                result = applyFourierLowPass( ...
                    app.OriginalImage, parameter);

            case 'Fourier High-Pass'
                result = applyFourierHighPass( ...
                    app.OriginalImage, parameter);

            case 'Fourier Band-Pass'
                % Temporary default low cutoff
                lowCutoff = 15;
                highCutoff = parameter;

                result = applyFourierBandPass( ...
                    app.OriginalImage, lowCutoff, highCutoff);

            otherwise
                error('Unknown operation selected.');

        end

        % Display processed image
        imshow(result, 'Parent', app.ProcessedAxes);

    catch ME
        uialert(app.UIFigure, ME.message, ...
            'Processing Error');
    end

        end

        % Button pushed function: UploadRefernceButton
        function UploadRefernceButtonPushed(app, event)

            
                [file, path] = uigetfile( ...
                    {'*.jpg;*.jpeg;*.png;*.bmp;*.tif', ...
                    'Image Files'}, ...
                    'Select Reference Image');

                if isequal(file, 0)
                    return;
                end

                app.ReferenceImage = imread(fullfile(path, file));

                uialert(app.UIFigure, ...
                    'Reference image uploaded successfully.', ...
                    'Success');


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EnhancementTab = uitab(app.TabGroup);
            app.EnhancementTab.Title = 'Enhancement';
            app.OriginalAxes = uiaxes(app.EnhancementTab);
            title(app.OriginalAxes, 'Original Image')
            xlabel(app.OriginalAxes, 'X')
            ylabel(app.OriginalAxes, 'Y')
            zlabel(app.OriginalAxes, 'Z')
            app.ProcessedAxes = uiaxes(app.EnhancementTab);
            title(app.ProcessedAxes, 'Processed Image')
            xlabel(app.ProcessedAxes, 'X')
            ylabel(app.ProcessedAxes, 'Y')
            zlabel(app.ProcessedAxes, 'Z')
            app.UploadImageButton = uibutton(app.EnhancementTab, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.ApplyButton = uibutton(app.EnhancementTab, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.DropDown = uidropdown(app.EnhancementTab);
            app.DropDown.Items = {'Histogram Equalization', 'Histogram Matching', 'Brightness Adjustment', 'Contrast Adjustment', 'Mean Filter', 'Median Filter', 'Laplacian Sharpening', 'High-Pass Sharpening', 'Fourier Low-Pass', 'Fourier High-Pass', 'Fourier Band-Pass'};
            app.DropDown.Value = 'Histogram Equalization';
            app.OperationLabel = uilabel(app.EnhancementTab);
            app.OperationLabel.Text = 'Operation';
            app.ParameterLabel = uilabel(app.EnhancementTab);
            app.ParameterLabel.Text = 'Parameter';
            app.ParameterEditField = uieditfield(app.EnhancementTab, 'numeric');
            app.ParameterEditField.Value = 1;
            app.UploadRefernceButton = uibutton(app.EnhancementTab, 'push');
            app.UploadRefernceButton.ButtonPushedFcn = createCallbackFcn(app, @UploadRefernceButtonPushed, true);
            app.UploadRefernceButton.Text = 'Upload Refernce';
            app.SegmentationTab = uitab(app.TabGroup);
            app.SegmentationTab.Title = 'Segmentation';
            app.TransformationTab = uitab(app.TabGroup);
            app.TransformationTab.Title = 'Transformation';
            app.DigitrecognitionTab = uitab(app.TabGroup);
            app.DigitrecognitionTab.Title = 'Digit recognition';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SmartImageToolbox

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end